classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        PanelFinalneSkore  matlab.ui.container.Panel
        Label              matlab.ui.control.Label
        PanelZoradenie     matlab.ui.container.Panel
        ButtonA            matlab.ui.control.Button
        OdpovedA5          matlab.ui.control.NumericEditField
        LabelA5            matlab.ui.control.Label
        OdpovedA4          matlab.ui.control.NumericEditField
        LabelA4            matlab.ui.control.Label
        OdpovedA3          matlab.ui.control.NumericEditField
        LabelA3            matlab.ui.control.Label
        OdpovedA2          matlab.ui.control.NumericEditField
        LabelA2            matlab.ui.control.Label
        OdpovedA1          matlab.ui.control.NumericEditField
        LabelA1            matlab.ui.control.Label
        OtazkaA            matlab.ui.control.Label
        SkoreLabel         matlab.ui.control.Label
        PanelAnoNie        matlab.ui.container.Panel
        ButtonC            matlab.ui.control.StateButton
        CheckBox5          matlab.ui.control.CheckBox
        CheckBox4          matlab.ui.control.CheckBox
        CheckBox3          matlab.ui.control.CheckBox
        CheckBox2          matlab.ui.control.CheckBox
        CheckBox1          matlab.ui.control.CheckBox
        LabelC5            matlab.ui.control.Label
        LabelC4            matlab.ui.control.Label
        LabelC3            matlab.ui.control.Label
        LabelC2            matlab.ui.control.Label
        LabelC1            matlab.ui.control.Label
        OtazkaC            matlab.ui.control.Label
        PanelDoplnenie     matlab.ui.container.Panel
        ButtonB            matlab.ui.control.Button
        OdpovedB5          matlab.ui.control.EditField
        LabelB5            matlab.ui.control.Label
        OdpovedB4          matlab.ui.control.EditField
        LabelB4            matlab.ui.control.Label
        OdpovedB3          matlab.ui.control.EditField
        LabelB3            matlab.ui.control.Label
        OdpovedB2          matlab.ui.control.EditField
        LabelB2            matlab.ui.control.Label
        OdpovedB1          matlab.ui.control.EditField
        LabelB1            matlab.ui.control.Label
        OtazkaB            matlab.ui.control.Label
        LabelSkore         matlab.ui.control.Label
    end

    
    properties (Access = private)
        skore % Description
        otazky % Description
        labels % Description
        odpovede % Description
        spravneOdpovede % Description
        kategoria % Description
        panely % Description
        pocetkol % Description
    end
    
    methods (Access = private)
        
        function pridatSkore(app,pocet)
            app.skore = app.skore + pocet;
            app.LabelSkore.Text = int2str(app.skore);
            display(app.skore);
        end
        
        function nahodnaOtazka(app)
            app.pocetkol = app.pocetkol - 1;
            if app.pocetkol == 0
               app.finalneskore();
            end
            app.kategoria = (floor(rand()*3+1));
            indexOtazky = (floor(rand()*8+1));
            app.labels(app.kategoria,1).Text = app.otazky(indexOtazky,1,app.kategoria);
            for i=1:5
                app.labels(app.kategoria,i+1).Text = app.otazky(indexOtazky,i+1,app.kategoria);
                app.spravneOdpovede(i) = app.otazky(indexOtazky,i + 6,app.kategoria);
            end
            for i=1:3
                app.panely(i).Visible = i==app.kategoria;
            end
        end
        
        function finalneskore(app)
            for i=1:3
                app.panely(i).Visible = false;
            end 
            app.PanelFinalneSkore.Visible = true;
            app.Label.Text = ("Tvoje finalne skore je" + ' ' + app.skore);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.skore = 0;
            app.pocetkol = 10;
            app.PanelFinalneSkore.Visible = false;
            A = {
            'Seradte staty dle rozlohy 1=nejvetsi','Indie','Kanada','Sudan','Kazachstan','USA','3','1','5','4','2';
            'Seradte oceany/more dle velikosti 1=nejvetsi','Tichy ocean','Tasmanovo more','Severni ledovy ocean','Koralove more','Karibske more','1','3','2','4','5';
            'Seradte staty podle poctu veznu na obyvatele 1=nejvyssi','Brazilie','Rusko','Turkmenistan','USA','El Salvador','5','4','3','1','2';
            'Seradte pokerove kombinace 1=nejvyssi','barva','dva pary','trojice stejne hodnoty','ctverice stejne hodnoty','postupka','2','5','4','1','3';
            'Seradte mesta podle poctu obyvatel 1=nejvyssi','Pariz','Bombaj','Tokio','Sanghaj','Londyn','5','3','1','2','4';
            'Seradte organy dle umisteni v lidskem tele 1=nejvyse','srdce','jatra','hypothalamus','ledviny','tenke strevo','2','3','1','4','5';
            'Seradte staty dle poctu obeti 2. svetove valky 1=nejvyssi','Italie','Japonsko','Nemecko','Cina','Velka Britanie','5','3','1','2','4';
            'Seradte budovy podle vysky 1=nejvyssi','Tokyo Skytree','Willis Tower','Oriental Pearl Tower','Petronas Towers','Taipei 101','1','5','3','4','2';
            };
            B = {
            'Na ktery hudebni nastroj hral/a nebo hraje?','Louis Armstrong','Benny Goodman','Phil Collins','George Harrison','Miles Davis','trubka','klarinet','bici','kytara','trubka';
            'Pro kterou zemi je typicke toto jidlo?','ramen','tom yum','souvlaki','paella','rizoto','japonsko','thajsko','recko','spanelsko','italie';
            'Co se meri v nasledujicich jednotkach?','kelvin','newton','furlong','pascal','watt','teplota','sila','delka','tlak','vykon';
            'Ke kteremu sportu patri tento klub?','Leicester Tigers','West Ham United','New York Yankees','Miami Heat','AC Milan','ragby','fotbal','basebal','basketbal','fotbal';
            'Ve ktere zemi se nachazi tato univerzita?','Lomonosovova univerzita','Yale','Sorbonna','La Sapienza','Sranford','rusko','usa','francie','italie','usa';
            'O plemeno ktereho zvirete se jedna?','briard','anglicky beran','lipican','sphynx','appaloosa','pes','kralik','kun','kocka','kun';
            'Hlavni mesto ktereho statu je ?','Teheran','Kuala Lumpur','Santiago','Rabat','Varsava','iran','malajsie','chile','maroko','polsko';
            'Ze ktere zeme pochazi tato hudebni skupina?','AC/DC','Rammstein','Nirvana','Nightwish','Nickelback','australie','nemecko','usa','finsko','kanada';
            };
            C = {
            'Znala Evropa ve stredoveku tuto potravinu?','kava','zeli','brambory','med','cibule','nie','ano','nie','ano','ano';
            'Nachazi se tento stat ve stejnem casovem pasmu jako CR?','Nigerie','Svedsko','Malta','Angola','Alzirsko','ano','ano','ano','ano','ano';
            'Patri mezi top10 nejvetsich ostrovu sveta?','Borneo','Gronsko','Kreta','Java','Viktoriin ostrov','ano','ano','nie','nie','ano';
            'Pristal tento clovek na Mesici?','Alan Shepard','Jurij Gagarin','Neil Armstrong','Edgar Mitchell','John Young','ano','nie','ano','ano','ano';
            'Jedna se o bitvu druhe svetove valky?','bitva v Adrenach','bitva u Stalingradu','bitva u Slavkova','vylodeni v Normandii','ostravska operace','ano','ano','nie','ano','ano';
            'Ma tento stat vice nez 100 milionu obyvatel?','Argentina','Nigerie','USA','Etiopie','Kena','nie','ano','ano','ano','nie';
            'Je nebo byl architektem?','Edvard Munch','Vlado Milunic','Josef Pleskot','Le Corbusier','Antoni Gaudi','nie','ano','ano','ano','ano';
            'Jedna se o dinosaura?','Parasaurolophus','Triceratops','Kentrosaurus','Satyrus','Medinosaurus','ano','ano','ano','nie','nie';
            };
            app.otazky = cat(3,A,B,C);
            app.panely = [app.PanelZoradenie,app.PanelDoplnenie,app.PanelAnoNie];
            app.labels = [app.OtazkaA,app.LabelA1,app.LabelA2,app.LabelA3,app.LabelA4,app.LabelA5;
                          app.OtazkaB,app.LabelB1,app.LabelB2,app.LabelB3,app.LabelB4,app.LabelB5;
                          app.OtazkaC,app.LabelC1,app.LabelC2,app.LabelC3,app.LabelC4,app.LabelC5;];
            app.odpovede = [app.OdpovedA1,app.OdpovedA2,app.OdpovedA3,app.OdpovedA4,app.OdpovedA5;
                            app.OdpovedB1,app.OdpovedB2,app.OdpovedB3,app.OdpovedB4,app.OdpovedB5;
                            app.CheckBox1,app.CheckBox2,app.CheckBox3,app.CheckBox4,app.CheckBox5];
            app.spravneOdpovede = {'','','','',''}';
            
            app.nahodnaOtazka();


        end

        % Button pushed function: ButtonA
        function ButtonAPushed(app, event)
            for i=1:5
                if num2str(app.odpovede(app.kategoria,i).Value) == app.spravneOdpovede{i}
                    app.pridatSkore(1);
                end
                app.odpovede(app.kategoria,i).Value = 0;
            end
            app.nahodnaOtazka();
        end

        % Button pushed function: ButtonB
        function ButtonBPushed(app, event)
            for i=1:5
                odpoved = app.odpovede(app.kategoria,i).Value;
                app.odpovede(app.kategoria,i).Value = '';
                if size(size(odpoved)) == 1
                    continue;
                end
                if size(odpoved,2) ~= size(app.spravneOdpovede{i},2)
                    continue;
                end
                if odpoved == app.spravneOdpovede{i}
                    app.pridatSkore(1);
                end
            end
            app.nahodnaOtazka();
        end

        % Value changed function: ButtonC
        function ButtonCValueChanged(app, event)
            for i=1:5
                if app.odpovede(app.kategoria,i).Value == (app.spravneOdpovede{i} == 'ano')
                    app.pridatSkore(1);
                end
                app.odpovede(app.kategoria,i).Value = false;
            end
            app.nahodnaOtazka();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LabelSkore = uilabel(app.UIFigure);
            app.LabelSkore.Text = '0';
            app.PanelDoplnenie = uipanel(app.UIFigure);
            app.OtazkaB = uilabel(app.PanelDoplnenie);
            app.OtazkaB.Text = '';
            app.LabelB1 = uilabel(app.PanelDoplnenie);
            app.LabelB1.Text = '';
            app.OdpovedB1 = uieditfield(app.PanelDoplnenie, 'text');
            app.LabelB2 = uilabel(app.PanelDoplnenie);
            app.LabelB2.Text = '';
            app.OdpovedB2 = uieditfield(app.PanelDoplnenie, 'text');
            app.LabelB3 = uilabel(app.PanelDoplnenie);
            app.LabelB3.Text = '';
            app.OdpovedB3 = uieditfield(app.PanelDoplnenie, 'text');
            app.LabelB4 = uilabel(app.PanelDoplnenie);
            app.LabelB4.Text = '';
            app.OdpovedB4 = uieditfield(app.PanelDoplnenie, 'text');
            app.LabelB5 = uilabel(app.PanelDoplnenie);
            app.LabelB5.Text = '';
            app.OdpovedB5 = uieditfield(app.PanelDoplnenie, 'text');
            app.ButtonB = uibutton(app.PanelDoplnenie, 'push');
            app.ButtonB.ButtonPushedFcn = createCallbackFcn(app, @ButtonBPushed, true);
            app.ButtonB.Text = '(>.<)';
            app.PanelAnoNie = uipanel(app.UIFigure);
            app.OtazkaC = uilabel(app.PanelAnoNie);
            app.OtazkaC.Text = '';
            app.LabelC1 = uilabel(app.PanelAnoNie);
            app.LabelC1.Text = '';
            app.LabelC2 = uilabel(app.PanelAnoNie);
            app.LabelC2.Text = '';
            app.LabelC3 = uilabel(app.PanelAnoNie);
            app.LabelC3.Text = '';
            app.LabelC4 = uilabel(app.PanelAnoNie);
            app.LabelC4.Text = '';
            app.LabelC5 = uilabel(app.PanelAnoNie);
            app.LabelC5.Text = '';
            app.CheckBox1 = uicheckbox(app.PanelAnoNie);
            app.CheckBox1.Text = '';
            app.CheckBox2 = uicheckbox(app.PanelAnoNie);
            app.CheckBox2.Text = '';
            app.CheckBox3 = uicheckbox(app.PanelAnoNie);
            app.CheckBox3.Text = '';
            app.CheckBox4 = uicheckbox(app.PanelAnoNie);
            app.CheckBox4.Text = '';
            app.CheckBox5 = uicheckbox(app.PanelAnoNie);
            app.CheckBox5.Text = '';
            app.ButtonC = uibutton(app.PanelAnoNie, 'state');
            app.ButtonC.ValueChangedFcn = createCallbackFcn(app, @ButtonCValueChanged, true);
            app.ButtonC.Text = '(-_-)';
            app.SkoreLabel = uilabel(app.UIFigure);
            app.SkoreLabel.Text = 'Skore';
            app.PanelZoradenie = uipanel(app.UIFigure);
            app.OtazkaA = uilabel(app.PanelZoradenie);
            app.OtazkaA.Text = '';
            app.LabelA1 = uilabel(app.PanelZoradenie);
            app.LabelA1.Text = '';
            app.OdpovedA1 = uieditfield(app.PanelZoradenie, 'numeric');
            app.LabelA2 = uilabel(app.PanelZoradenie);
            app.LabelA2.Text = '';
            app.OdpovedA2 = uieditfield(app.PanelZoradenie, 'numeric');
            app.LabelA3 = uilabel(app.PanelZoradenie);
            app.LabelA3.Text = '';
            app.OdpovedA3 = uieditfield(app.PanelZoradenie, 'numeric');
            app.LabelA4 = uilabel(app.PanelZoradenie);
            app.LabelA4.Text = '';
            app.OdpovedA4 = uieditfield(app.PanelZoradenie, 'numeric');
            app.LabelA5 = uilabel(app.PanelZoradenie);
            app.LabelA5.Text = '';
            app.OdpovedA5 = uieditfield(app.PanelZoradenie, 'numeric');
            app.ButtonA = uibutton(app.PanelZoradenie, 'push');
            app.ButtonA.ButtonPushedFcn = createCallbackFcn(app, @ButtonAPushed, true);
            app.ButtonA.Text = '(O.O)';
            app.PanelFinalneSkore = uipanel(app.UIFigure);
            app.Label = uilabel(app.PanelFinalneSkore);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end